classdef HydroScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MotorsTab                     matlab.ui.container.Tab
        EditField_SerialPort          matlab.ui.control.EditField
        DropDown_MovementType         matlab.ui.control.DropDown
        EditField_tav                 matlab.ui.control.EditField
        EditField_trv                 matlab.ui.control.EditField
        TButton_Minus                 matlab.ui.control.Button
        TButton_Plus                  matlab.ui.control.Button
        Lamp_Motor                    matlab.ui.control.Lamp
        ConnectButton_Motor           matlab.ui.control.StateButton
        GohomeButton                  matlab.ui.control.Button
        EditField_zav                 matlab.ui.control.EditField
        EditField_yav                 matlab.ui.control.EditField
        EditField_xav                 matlab.ui.control.EditField
        EditField_zrv                 matlab.ui.control.EditField
        EditField_yrv                 matlab.ui.control.EditField
        EditField_xrv                 matlab.ui.control.EditField
        SethomeButton                 matlab.ui.control.Button
        ZButton_Minus                 matlab.ui.control.Button
        ZButton_Plus                  matlab.ui.control.Button
        YButton_Minus                 matlab.ui.control.Button
        YButton_Plus                  matlab.ui.control.Button
        XButton_Minus                 matlab.ui.control.Button
        XButton_Plus                  matlab.ui.control.Button
        PicoTab                       matlab.ui.container.Tab
        ScopeAcquisitionPanel         matlab.ui.container.Panel
        VoltageRangeListBox           matlab.ui.control.ListBox
        VListBoxLabel                 matlab.ui.control.Label
        PreTriggerTimeEditField       matlab.ui.control.EditField
        TriggertimemsLabel            matlab.ui.control.Label
        PostTriggerTimeEditField      matlab.ui.control.EditField
        PulseRepeatkHzEditField       matlab.ui.control.EditField
        RepeatfrequencykHzLabel       matlab.ui.control.Label
        SliderYaxis                   matlab.ui.control.Slider
        SliderAverages                matlab.ui.control.Slider
        Lamp_Pico                     matlab.ui.control.Lamp
        ConnectButton_Pico            matlab.ui.control.StateButton
        PulseButton                   matlab.ui.control.StateButton
        PulseGeneratorPanel           matlab.ui.container.Panel
        PulseShowWhat                 matlab.ui.control.DropDown
        PulseFilterFrequencyLow       matlab.ui.control.EditField
        HPFMHzLabel                   matlab.ui.control.Label
        PulseFilterFrequencyHigh      matlab.ui.control.EditField
        PulseTypeDropDown             matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        PulseAmplitudeVEditField      matlab.ui.control.EditField
        AmpVLabel                     matlab.ui.control.Label
        PulseNumberofcyclesEditField  matlab.ui.control.EditField
        NcyclesLabel                  matlab.ui.control.Label
        PulseCarrierFrequencyMHzEditField  matlab.ui.control.EditField
        CarrierFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        UIAxes_Pico                   matlab.ui.control.UIAxes
        MotorScanTab                  matlab.ui.container.Tab
        AveragesLabel                 matlab.ui.control.Label
        EditField_ScanAverages        matlab.ui.control.EditField
        widthLabel                    matlab.ui.control.Label
        StartusLabel                  matlab.ui.control.Label
        EditField_timegate_width      matlab.ui.control.EditField
        EditField_timegate_start      matlab.ui.control.EditField
        ScanMotorButton               matlab.ui.control.StateButton
        EditField_rotation_step       matlab.ui.control.EditField
        EditField_line_step           matlab.ui.control.EditField
        EditField_rotation_range      matlab.ui.control.EditField
        RotateCheckBox                matlab.ui.control.CheckBox
        EditField_line_range          matlab.ui.control.EditField
        LineDropDown_axis_selection   matlab.ui.control.DropDown
        UIAxesPressure                matlab.ui.control.UIAxes
        FrequencyResponseTab          matlab.ui.container.Tab
        stepLabel                     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        FrequencyMHzLabel             matlab.ui.control.Label
        AveragesLabel_2               matlab.ui.control.Label
        EditField_ScanAverages_2      matlab.ui.control.EditField
        WidthLabel_2                  matlab.ui.control.Label
        StartusLabel_2                matlab.ui.control.Label
        EditField_timegate_width_2    matlab.ui.control.EditField
        EditField_timegate_start_2    matlab.ui.control.EditField
        ScanFrequencyButton           matlab.ui.control.StateButton
        EditField_freq_stop           matlab.ui.control.EditField
        EditField_freq_step           matlab.ui.control.EditField
        EditField_freq_start          matlab.ui.control.EditField
        UIAxesPressure_2              matlab.ui.control.UIAxes
        DataControlTab                matlab.ui.container.Tab
        SaveDataCheckBox              matlab.ui.control.CheckBox
        BrowseButton                  matlab.ui.control.StateButton
        IdentificationEditField       matlab.ui.control.EditField
        IdentificationEditFieldLabel  matlab.ui.control.Label
        OutputFolder                  matlab.ui.control.EditField
        OutputFolderEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        Motor % MotorXYZ class
        Pico % PicoScope class
        Acou % Acoustics class
        Pressure
        PressureIndex
    end

    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Line (geometric) scan function
        function [p x] = LineScan(app, MotorSelection, LineMagnitude, LineStep)
            LM = abs(LineMagnitude);
            LS = abs(LineStep);

            % adjust Line Step if neccessary
            N = round(2*LM/LS);
            LS = 2*LM/N*sign(LineStep);
            app.EditField_line_step.Value = sprintf('%5.3f',LS);

            p = zeros(1,N+1);
            x = -LM:abs(LS):LM;

            if LineStep > 0
                MoveMotorOnce_absolute(app, MotorSelection, -LM);
                ix = 1:length(x);
            elseif LineStep <0
                MoveMotorOnce_absolute(app, MotorSelection, LM);
                ix = length(x):-1:1;
            else
                fprintf('Errror: zero step\n')
                return;
            end
            
            ScanAverages = str2num(app.EditField_ScanAverages.Value);
            %%
            % filter
            f1 = str2num(app.PulseFilterFrequencyLow.Value)/1e3; % GHz
            f2 = str2num(app.PulseFilterFrequencyHigh.Value)/1e3; % GHz


            [sin, tin] = app.Pico.pulse_average(ScanAverages, f1,f2);
            t=(1:length(sin))*tin/1e3;
            plot(app.UIAxes_Pico, t, sin)

            % Time gate
            TG(1) = str2num( app.EditField_timegate_start.Value );
            TG(2) = TG(1) + str2num( app.EditField_timegate_width.Value );
            TGindex = find( t>TG(1) & t<TG(2) );

            app.Pressure( app.PressureIndex, ix(1)) = app.Acou.rms( sin(TGindex) );

            for n = 1:N
                MoveMotorOnce_relative(app, MotorSelection, LS);
                [sin, tin] = app.Pico.pulse_average(ScanAverages, f1,f2);
                
                plot(app.UIAxes_Pico, t, sin, 'b-', t(TGindex), sin(TGindex), 'r-');

                app.Pressure( app.PressureIndex, ix(n+1) ) = app.Acou.rms( sin(TGindex) );

                if app.PressureIndex ==1
                    plot(app.UIAxesPressure, x, app.Pressure);
                else
                    imagesc(app.UIAxesPressure, app.Pressure);
                end

                if ~app.ScanMotorButton.Value, break, end
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Frequency scan function
        function [p x] = FrequencyScan(app, FrequencyVector)

            N = length(FrequencyVector);
            
            app.Pressure = zeros(1,N);
            
            ScanAverages = str2num(app.EditField_ScanAverages_2.Value);
            %%
            % filter
            f1 = str2num(app.PulseFilterFrequencyLow.Value)/1e3; % GHz
            f2 = str2num(app.PulseFilterFrequencyHigh.Value)/1e3; % GHz


            [sin, tin] = app.Pico.pulse_average(ScanAverages, f1,f2);
            t=(1:length(sin))*tin/1e3;
            plot(app.UIAxes_Pico, t, sin)

            % Time gate
            TG(1) = str2num( app.EditField_timegate_start_2.Value );
            TG(2) = TG(1) + str2num( app.EditField_timegate_width_2.Value );
            TGindex = find( t>TG(1) & t<TG(2) );

%             app.Pressure( app.PressureIndex, ix(1)) = app.Acou.rms( sin(TGindex) );
            SavedCarrierFrequency = str2num(app.PulseCarrierFrequencyMHzEditField.Value)*1e6;

            for n = 1:N
                % ##############
                CarrierFrequency = FrequencyVector(n)*1e6;
                app.PulseCarrierFrequencyMHzEditField.Value = sprintf('%5.3f',CarrierFrequency/1e6);

                RepeatFrequency = str2num(app.PulseRepeatkHzEditField.Value)*1e3;
                %             CarrierFrequency = str2num(app.PulseCarrierFrequencyMHzEditField.Value)*1e6;
                NumberCycles = str2num(app.PulseNumberofcyclesEditField.Value);
                Amplitude = str2num(app.PulseAmplitudeVEditField.Value);
                PulseType = app.PulseTypeDropDown.Value;

                app.Pico.configure_generator(CarrierFrequency, NumberCycles, RepeatFrequency, Amplitude, PulseType);

                PreTriggerTime = str2num(app.PreTriggerTimeEditField.Value)*1e3; % convert from microseconds to nano
                PostTriggerTime = str2num(app.PostTriggerTimeEditField.Value)*1e3; % convert from microseconds to nano

                app.Pico.configure_scope_acquisition(PreTriggerTime, PostTriggerTime);
                % ##############
                [sin, tin] = app.Pico.pulse_average(ScanAverages, f1,f2);
                if length(t)~=length(sin)
                    t=(1:length(sin))*tin/1e3;
                end
                
                plot(app.UIAxes_Pico, t, sin, 'b-', t(TGindex), sin(TGindex), 'r-');

                app.Pressure( app.PressureIndex, n ) = app.Acou.rms( sin(TGindex) );

                if app.PressureIndex ==1
                    plot(app.UIAxesPressure_2, FrequencyVector, app.Pressure);
                else
                    imagesc(app.UIAxesPressure_2, app.Pressure);
                end
                drawnow;

                if ~app.ScanFrequencyButton.Value, break, end
            end
            app.PulseCarrierFrequencyMHzEditField.Value = sprintf('%5.3f',SavedCarrierFrequency/1e6);

            p = app.Pressure;
            x = FrequencyVector;
        end

        function MoveMotorOnce_relative(app, MotorSelection, Distance)
            app.Motor.move_relative(MotorSelection, Distance);
            switch MotorSelection
                case 1
                    app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 2
                    app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 3
                    app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 4
                    app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
            end
            pause(abs(Distance)/16);
        end

        function MoveMotorOnce_absolute(app, MotorSelection, Location)
            app.Motor.move_absolute(MotorSelection, Location);
            switch MotorSelection
                case 1
                    app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 2
                    app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 3
                    app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 4
                    app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
            end
            pause(abs(Location)/16);
        end

        function MoveMotor_RA_PM(app, MotorNumber, Distance, TypeOfMovement, PlusMinus)
            switch TypeOfMovement
                case 'Relative'
                    app.Motor.move_relative(MotorNumber,Distance*PlusMinus);
                case 'Absolute'
                    app.Motor.move_absolute(MotorNumber,Distance);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Acou = Acoustics;
        end

        % Value changed function: ConnectButton_Pico
        function ConnectButton_PicoValueChanged(app, event)
            value = app.ConnectButton_Pico.Value;
            if value
                app.Pico = PicoScope;

                app.Pico.connect();
                app.Lamp_Pico.Color = [0 1 0];

                ScopeVoltageRange = find(matches(app.VoltageRangeListBox.Items,app.VoltageRangeListBox.Value))-1;
                app.Pico.configure_scope(ScopeVoltageRange);

                PulseType = app.PulseTypeDropDown.Value;

                RepeatFrequency = str2num(app.PulseRepeatkHzEditField.Value)*1e3;
                CarrierFrequency = str2num(app.PulseCarrierFrequencyMHzEditField.Value)*1e6;
                NumberCycles = str2num(app.PulseNumberofcyclesEditField.Value);
                Amplitude = str2num(app.PulseAmplitudeVEditField.Value);

                app.Pico.configure_generator(CarrierFrequency, NumberCycles, RepeatFrequency, Amplitude, PulseType);
            else
                app.Pico.disconnect();
                app.Lamp_Pico.Color = [1 0 0];
            end
        end

        % Value changed function: PulseButton
        function PulseButtonValueChanged(app, event)
            if ~isvalid(app.Pico.ps5000aDeviceObj), disp('ERROR: connect to Pico scope firts'), return, end
            
            [sold, tin] = app.Pico.pulse();
            t=(1:length(sold))*tin/1e3; % in microseconds
            L = length(t); 
            % f = (0:floor(L/2))/L/(tin/1e3);

            nav = 2^(10*app.SliderAverages.Value);
            ylimit = 2^(10*(app.SliderYaxis.Value-1));
            value = app.PulseButton.Value;

            while value
                [snew, tin] = app.Pico.pulse();

                    %%
                    % filter
                    f1 = str2num(app.PulseFilterFrequencyLow.Value)/1e3; % GHz
                    f2 = str2num(app.PulseFilterFrequencyHigh.Value)/1e3; % GHz
                    if f1>0 || f2>0,
                        snew = app.Pico.band_pass_filter(snew,1/tin,min(f1,f2),max(f1,f2)); % tin is ns 1/ns = GHz
                    end


                if length(snew) ~= length(sold),
                    sold = snew;
                    t = (1:length(sold))*tin/1e3; L = length(t);
                    f = (0:floor(L/2))/L/(tin/1e3); % in MHz
                end
                s = (snew + (nav-1)*sold)/nav;


                switch app.PulseShowWhat.Value
                    case 'WFM'
                        mxs = max(abs(s))*ylimit;
                        mns = mean(s);
                        plot(app.UIAxes_Pico, t, s),
                        xlabel(app.UIAxes_Pico, 'Time (\mus)')
                        ylabel(app.UIAxes_Pico, 'Amplitude (V)')
                        app.UIAxes_Pico.YScale = 'Linear';
                        app.UIAxes_Pico.XScale = 'Linear';
                        if mxs>0, ylim(app.UIAxes_Pico,[mns-mxs mns+mxs]); end
                        xlim(app.UIAxes_Pico,[min(t) max(t)]);
                    case 'FFT'
%                         P2 = abs(fft(s)/L);
%                         P1 = P2(1:length(f));
%                         P1(2:end-1) = 2*P1(2:end-1);
%                         mxs = max(abs(P1))*ylimit;
%                         mns = mean(P1);
%                         plot(app.UIAxes_Pico, f, 20*log10(P1)),
%                         app.UIAxes_Pico.YScale = 'Linear';
%                         xlabel(app.UIAxes_Pico, 'Frequency (MHz)')
%                         ylim(app.UIAxes_Pico,[-50 50]); 
%                          xlim(app.UIAxes_Pico,[min(f) max(f)]);
                         Hs=spectrum.welch;
                         Hs.SegmentLength=floor(length(s)/4);

                         psdwelch = psd(Hs,s, 'NFFT',Hs.SegmentLength, 'Fs',1e9/tin); % Hz
                         p = 10*log10( psdwelch.Data );
                         f = psdwelch.Frequencies;
%                          semilogx(app.UIAxes_Pico, f/1e6,p), grid
                         plot(app.UIAxes_Pico, f/1e6, p)
                         app.UIAxes_Pico.YScale = 'Log';
                         app.UIAxes_Pico.XScale = 'Linear';
                         xlim(app.UIAxes_Pico,[f(1)/1e6 f(end)/1e6]);
                         ylim(app.UIAxes_Pico,[-90 -40]);
                         xlabel(app.UIAxes_Pico, 'Frequency (Hz)'), 
                         ylabel(app.UIAxes_Pico, 'PSD (dB)'),
                end
                

                sold = s;

                nav = 2^(10*app.SliderAverages.Value);
                ylimit = 2^(10*(app.SliderYaxis.Value-1));
                value = app.PulseButton.Value;

                drawnow
            end

        end

        % Value changed function: ConnectButton_Motor
        function ConnectButton_MotorValueChanged(app, event)
            value = app.ConnectButton_Motor.Value;
            if value
                app.Motor = MotorXYZT;
                SerialPort = app.EditField_SerialPort.Value;
                status = app.Motor.connect(SerialPort);
                if status
                    app.Lamp_Motor.Color = [0 1 0];
                end
                SethomeButtonPushed(app, event);
            else
                app.Motor.disconnect();
                app.Lamp_Motor.Color = [1 0 0];
            end
        end

        % Button pushed function: SethomeButton
        function SethomeButtonPushed(app, event)
            app.Motor.sethome();
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(1));
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(2));
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(3));
            app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(4));
        end

        % Button pushed function: XButton_Plus
        function XButton_PlusPushed(app, event)
            motornum = 1;
            linear_movement = str2num(app.EditField_xrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: XButton_Minus
        function XButton_MinusPushed(app, event)
            motornum = 1;
            linear_movement = str2num(app.EditField_xrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: YButton_Plus
        function YButton_PlusPushed(app, event)
            motornum = 2;
            linear_movement = str2num(app.EditField_yrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: YButton_Minus
        function YButton_MinusPushed(app, event)
            motornum = 2;
            linear_movement = str2num(app.EditField_yrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: ZButton_Plus
        function ZButton_PlusPushed(app, event)
            motornum = 3;
            linear_movement = abs( str2num(app.EditField_zrv.Value) );
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: ZButton_Minus
        function ZButton_MinusPushed(app, event)
            motornum = 3;
            linear_movement = str2num(app.EditField_zrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: TButton_Plus
        function TButton_PlusPushed(app, event)
            motornum = 4;
            linear_movement = str2num(app.EditField_trv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_tav.Value = sprintf('%7.1f', app.Motor.curloc(motornum));
        end

        % Button pushed function: TButton_Minus
        function TButton_MinusPushed(app, event)
            motornum = 4;
            linear_movement = str2num(app.EditField_trv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_tav.Value = sprintf('%7.1f', app.Motor.curloc(motornum));
        end

        % Button pushed function: GohomeButton
        function GohomeButtonPushed(app, event)
            app.Motor.gohome()
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(1));
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(2));
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(3));
            app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(4));
        end

        % Value changed function: ScanMotorButton
        function ScanMotorButtonValueChanged(app, event)
            value = app.ScanMotorButton.Value;
            if value
                if isempty(app.Pico) || ~isvalid(app.Pico.ps5000aDeviceObj)
                    errordlg('Pico scope is not connected'); return,
                end

                if isempty(app.Motor) || ~isvalid(app.Motor.seport)
                    errordlg('Motor stage is not connected'); return,
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                SethomeButtonPushed(app, event);

                MotorSelection = find(contains(app.LineDropDown_axis_selection.Items,app.LineDropDown_axis_selection.Value));
                scanline2save = @(x,y) y(x); 

                %%
                % adjust Line step
                LineMagnitude = str2num(app.EditField_line_range.Value);
                LineStep = str2num(app.EditField_line_step.Value);
                NL = round(2*abs(LineMagnitude)/abs(LineStep));
                LS = 2*abs(LineMagnitude)/NL;
                app.EditField_line_step.Value = sprintf('%5.3f',LS);

                if app.RotateCheckBox.Value

                    RotationMagnitude = str2num(app.EditField_rotation_range.Value);
                    app.EditField_rotation_range.Value = sprintf('%4.1f',abs(RotationMagnitude));

                    RotationStep = str2num(app.EditField_rotation_step.Value);
                    app.EditField_rotation_step.Value = sprintf('%4.1f',abs(RotationStep));

                    scantype2save = ['R',scanline2save(MotorSelection,'XYZR')];

                    GohomeButtonPushed(app, []);

                    %%
                    % adjst rotation step
                    RM = abs(RotationMagnitude);
                    RS = abs(RotationStep);

                    NR = round(2*RotationMagnitude/RotationStep);
                    RS = 2*RM/NR;
                    app.EditField_rotation_step.Value = sprintf('%5.3f',RS);

                    r = -RM:RS:RM;
                    app.Pressure = zeros(NR+1, NL+1);

                    MoveMotorOnce_absolute(app, 4, -RM);

                    linmagn = LineMagnitude;
                    linstep = LineStep;

                    for n = 1:NR+1
                        app.PressureIndex = n;

                        [p, x] = LineScan(app, MotorSelection, linmagn, linstep);
                        linmagn = -linmagn;
                        linstep = -linstep;

                        if ~app.ScanMotorButton.Value, break, end
                        MoveMotorOnce_relative(app, 4, RS);
                    end
                    app.ScanMotorButton.Value = 0;
                else
                    scantype2save = scanline2save(MotorSelection,'XYZR');

                    app.Pressure = zeros(1, NL+1);
                    app.PressureIndex = 1;
                    [p, x] = LineScan(app, MotorSelection, LineMagnitude, LineStep);
                    app.ScanMotorButton.Value = 0;
                end
                GohomeButtonPushed(app, []);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.SaveDataCheckBox.Value
                    if isempty(app.OutputFolder.Value)
                        BrowseButtonValueChanged(app, event);
                    end

                    fprintf('Saving hydrophone scan data to %s\n', app.OutputFolder.Value);

                    id = app.IdentificationEditField.Value;
                    ts = datestr(now,30);
                    devidentification = [sprintf('Hydro %s-scan ',scantype2save),id,' ',ts];
                    save(fullfile(app.OutputFolder.Value,[devidentification,'.mat']));

                    newfigure = figure;
                    newfigure.Visible = 'off';
                    %  copyobj(app.UIAxesPressure, newfigure);
                    if app.PressureIndex ==1
                        plot(x, app.Pressure); xlabel('Distance (mm)'), ylabel('Pressure (MPa)')
                    else
                        imagesc(x,r, app.Pressure), xlabel('Distance (mm)'), ylabel('Angle (deg)')
                    end

                    saveas(newfigure, fullfile(app.OutputFolder.Value,[devidentification,'.jpg']));
                    delete(newfigure);
                end
            end
        end

        % Value changed function: PulseCarrierFrequencyMHzEditField
        function PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
            PulseType = app.PulseTypeDropDown.Value;

            RepeatFrequency = str2num(app.PulseRepeatkHzEditField.Value)*1e3;
            CarrierFrequency = str2num(app.PulseCarrierFrequencyMHzEditField.Value)*1e6;
            NumberCycles = str2num(app.PulseNumberofcyclesEditField.Value);
            Amplitude = str2num(app.PulseAmplitudeVEditField.Value);
            app.Pico.configure_generator(CarrierFrequency, NumberCycles, RepeatFrequency, Amplitude, PulseType);

            PreTriggerTime = str2num(app.PreTriggerTimeEditField.Value)*1e3; % convert from microseconds to nano
            PostTriggerTime = str2num(app.PostTriggerTimeEditField.Value)*1e3; % convert from microseconds to nano

            app.Pico.configure_scope_acquisition(PreTriggerTime, PostTriggerTime);
        end

        % Value changed function: PulseNumberofcyclesEditField
        function PulseNumberofcyclesEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseAmplitudeVEditField
        function PulseAmplitudeVEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseRepeatkHzEditField
        function PulseRepeatkHzEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseTypeDropDown
        function PulseTypeDropDownValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PreTriggerTimeEditField
        function PreTriggerTimeEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PostTriggerTimeEditField
        function PostTriggerTimeEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: ScanFrequencyButton
        function ScanFrequencyButtonValueChanged(app, event)
            value = app.ScanFrequencyButton.Value;
            if value
                if isempty(app.Pico) || ~isvalid(app.Pico.ps5000aDeviceObj)
%                     errordlg('Pico scope is not connected'); return;
                    BrowseButtonValueChanged(app, event);
                end

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %%
                % adjust frequency step
                f1 = str2num(app.EditField_freq_start.Value);
                f2 = str2num(app.EditField_freq_stop.Value);
                df = str2num(app.EditField_freq_step.Value);
                NF = round(abs(f2-f1)/abs(df));
                df = abs(f2-f1)/NF;
                app.EditField_freq_step.Value = sprintf('%5.3f',df);

                app.Pressure = zeros(1, NF+1);
                app.PressureIndex = 1;
                if f1<f2, fv = f1:abs(df):f2; else fv=f1:-abs(df):f2; end
                [p, x] = FrequencyScan(app, fv);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.SaveDataCheckBox.Value
                    if isempty(app.OutputFolder.Value)
                        BrowseButtonValueChanged(app, event);
                    end

                    fprintf('Saving data to %s\n', app.OutputFolder.Value);

                    id = app.IdentificationEditField.Value;
                    ts = datestr(now,30);
                    devidentification = ['Hydro F-scan ',id,' ',ts];
                    save(fullfile(app.OutputFolder.Value,[devidentification,'.mat']));

                    newfigure = figure;
                    newfigure.Visible = 'off';
                    % copyobj(app.UIAxesPressure_2, newfigure);
                    plot(x,p), xlabel('Freuency (MHz)'), ylabel('Pressure (MPa)')
                    saveas(newfigure, fullfile(app.OutputFolder.Value,[devidentification,'.jpg']));
                    delete(newfigure);
                end
            end
        end

        % Value changed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            value = app.BrowseButton.Value;
            if isempty(app.OutputFolder.Value) app.OutputFolder.Value=pwd; end
            app.OutputFolder.Value = uigetdir(app.OutputFolder.Value,'Select output folder');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorsTab = uitab(app.TabGroup);
            app.MotorsTab.Title = 'Motors';
            app.XButton_Plus = uibutton(app.MotorsTab, 'push');
            app.XButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @XButton_PlusPushed, true);
            app.XButton_Plus.Tag = 'xp';
            app.XButton_Plus.Text = 'X+';
            app.XButton_Minus = uibutton(app.MotorsTab, 'push');
            app.XButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @XButton_MinusPushed, true);
            app.XButton_Minus.Tag = 'xm';
            app.XButton_Minus.Text = 'X-';
            app.YButton_Plus = uibutton(app.MotorsTab, 'push');
            app.YButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @YButton_PlusPushed, true);
            app.YButton_Plus.Tag = 'yp';
            app.YButton_Plus.Text = 'Y+';
            app.YButton_Minus = uibutton(app.MotorsTab, 'push');
            app.YButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @YButton_MinusPushed, true);
            app.YButton_Minus.Tag = 'ym';
            app.YButton_Minus.Text = 'Y-';
            app.ZButton_Plus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PlusPushed, true);
            app.ZButton_Plus.Tag = 'zp';
            app.ZButton_Plus.Text = 'Z+';
            app.ZButton_Minus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_MinusPushed, true);
            app.ZButton_Minus.Tag = 'zm';
            app.ZButton_Minus.Text = 'Z-';
            app.SethomeButton = uibutton(app.MotorsTab, 'push');
            app.SethomeButton.ButtonPushedFcn = createCallbackFcn(app, @SethomeButtonPushed, true);
            app.SethomeButton.Text = 'Set home';
            app.EditField_xrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_xrv.Tag = 'xrv';
            app.EditField_xrv.Value = '1.0';
            app.EditField_yrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_yrv.Tag = 'yrv';
            app.EditField_yrv.Value = '1.0';
            app.EditField_zrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_zrv.Tag = 'zrv';
            app.EditField_zrv.Value = '1.0';
            app.EditField_xav = uieditfield(app.MotorsTab, 'text');
            app.EditField_xav.Tag = 'xav';
            app.EditField_xav.Editable = 'off';
            app.EditField_xav.Enable = 'off';
            app.EditField_xav.Value = '0.0';
            app.EditField_yav = uieditfield(app.MotorsTab, 'text');
            app.EditField_yav.Tag = 'yav';
            app.EditField_yav.Editable = 'off';
            app.EditField_yav.Enable = 'off';
            app.EditField_yav.Value = '0.0';
            app.EditField_zav = uieditfield(app.MotorsTab, 'text');
            app.EditField_zav.Tag = 'zav';
            app.EditField_zav.Editable = 'off';
            app.EditField_zav.Enable = 'off';
            app.EditField_zav.Value = '0.0';
            app.GohomeButton = uibutton(app.MotorsTab, 'push');
            app.GohomeButton.ButtonPushedFcn = createCallbackFcn(app, @GohomeButtonPushed, true);
            app.GohomeButton.Text = 'Go home';
            app.ConnectButton_Motor = uibutton(app.MotorsTab, 'state');
            app.ConnectButton_Motor.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_MotorValueChanged, true);
            app.ConnectButton_Motor.Text = 'Connect';
            app.Lamp_Motor = uilamp(app.MotorsTab);
            app.TButton_Plus = uibutton(app.MotorsTab, 'push');
            app.TButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @TButton_PlusPushed, true);
            app.TButton_Plus.Tag = 'zp';
            app.TButton_Plus.Text = 'T+';
            app.TButton_Minus = uibutton(app.MotorsTab, 'push');
            app.TButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @TButton_MinusPushed, true);
            app.TButton_Minus.Tag = 'zm';
            app.TButton_Minus.Text = 'T-';
            app.EditField_trv = uieditfield(app.MotorsTab, 'text');
            app.EditField_trv.Tag = 'zrv';
            app.EditField_trv.Value = '10.0';
            app.EditField_tav = uieditfield(app.MotorsTab, 'text');
            app.EditField_tav.Tag = 'zav';
            app.EditField_tav.Editable = 'off';
            app.EditField_tav.Enable = 'off';
            app.EditField_tav.Value = '0.0';
            app.DropDown_MovementType = uidropdown(app.MotorsTab);
            app.DropDown_MovementType.Items = {'Relative', 'Absolute', ''};
            app.DropDown_MovementType.Value = 'Relative';
            app.EditField_SerialPort = uieditfield(app.MotorsTab, 'text');
            app.EditField_SerialPort.Tag = 'xrv';
            app.EditField_SerialPort.Value = 'COM3';
            app.PicoTab = uitab(app.TabGroup);
            app.PicoTab.Title = 'Pico';
            app.UIAxes_Pico = uiaxes(app.PicoTab);
            xlabel(app.UIAxes_Pico, 'Time (\mus)')
            app.PulseGeneratorPanel = uipanel(app.PicoTab);
            app.PulseGeneratorPanel.Title = 'Pulse Generator';
            app.CarrierFrequencyMHzEditFieldLabel = uilabel(app.PulseGeneratorPanel);
            app.CarrierFrequencyMHzEditFieldLabel.Text = 'Carrier Frequency (MHz)';
            app.PulseCarrierFrequencyMHzEditField = uieditfield(app.PulseGeneratorPanel, 'text');
            app.PulseCarrierFrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseCarrierFrequencyMHzEditFieldValueChanged, true);
            app.PulseCarrierFrequencyMHzEditField.Value = '10.00';
            app.NcyclesLabel = uilabel(app.PulseGeneratorPanel);
            app.NcyclesLabel.Text = 'N cycles';
            app.PulseNumberofcyclesEditField = uieditfield(app.PulseGeneratorPanel, 'text');
            app.PulseNumberofcyclesEditField.ValueChangedFcn = createCallbackFcn(app, @PulseNumberofcyclesEditFieldValueChanged, true);
            app.PulseNumberofcyclesEditField.Value = '10';
            app.AmpVLabel = uilabel(app.PulseGeneratorPanel);
            app.AmpVLabel.Text = 'Vpp';
            app.PulseAmplitudeVEditField = uieditfield(app.PulseGeneratorPanel, 'text');
            app.PulseAmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @PulseAmplitudeVEditFieldValueChanged, true);
            app.PulseAmplitudeVEditField.Value = '0.1';
            app.TypeDropDownLabel = uilabel(app.PulseGeneratorPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.PulseTypeDropDown = uidropdown(app.PulseGeneratorPanel);
            app.PulseTypeDropDown.Items = {'Sinusoidal', 'Gaussian', 'Chirp'};
            app.PulseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseTypeDropDownValueChanged, true);
            app.PulseTypeDropDown.Value = 'Sinusoidal';
            app.PulseFilterFrequencyHigh = uieditfield(app.PulseGeneratorPanel, 'text');
            app.PulseFilterFrequencyHigh.Tooltip = {'Post trigger time'};
            app.PulseFilterFrequencyHigh.Value = '100.0';
            app.HPFMHzLabel = uilabel(app.PulseGeneratorPanel);
            app.HPFMHzLabel.Text = 'HPF (MHz)';
            app.PulseFilterFrequencyLow = uieditfield(app.PulseGeneratorPanel, 'text');
            app.PulseFilterFrequencyLow.Tooltip = {'Pre Trigger time'};
            app.PulseFilterFrequencyLow.Value = '0.1';
            app.PulseShowWhat = uidropdown(app.PulseGeneratorPanel);
            app.PulseShowWhat.Items = {'WFM', 'FFT'};
            app.PulseShowWhat.Value = 'WFM';
            app.PulseButton = uibutton(app.PicoTab, 'state');
            app.PulseButton.ValueChangedFcn = createCallbackFcn(app, @PulseButtonValueChanged, true);
            app.PulseButton.Text = 'Pulse';
            app.ConnectButton_Pico = uibutton(app.PicoTab, 'state');
            app.ConnectButton_Pico.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_PicoValueChanged, true);
            app.ConnectButton_Pico.Text = 'Connect';
            app.Lamp_Pico = uilamp(app.PicoTab);
            app.SliderAverages = uislider(app.PicoTab);
            app.SliderAverages.Limits = [0 1];
            app.SliderAverages.MajorTicks = [];
            app.SliderAverages.MinorTicks = [];
            app.SliderYaxis = uislider(app.PicoTab);
            app.SliderYaxis.Limits = [0 1];
            app.SliderYaxis.MajorTicks = [];
            app.SliderYaxis.MinorTicks = [];
            app.SliderYaxis.Value = 1;
            app.ScopeAcquisitionPanel = uipanel(app.PicoTab);
            app.ScopeAcquisitionPanel.Title = 'Scope Acquisition';
            app.RepeatfrequencykHzLabel = uilabel(app.ScopeAcquisitionPanel);
            app.RepeatfrequencykHzLabel.Text = 'PRF (kHz)';
            app.PulseRepeatkHzEditField = uieditfield(app.ScopeAcquisitionPanel, 'text');
            app.PulseRepeatkHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseRepeatkHzEditFieldValueChanged, true);
            app.PulseRepeatkHzEditField.Value = '100.0';
            app.PostTriggerTimeEditField = uieditfield(app.ScopeAcquisitionPanel, 'text');
            app.PostTriggerTimeEditField.ValueChangedFcn = createCallbackFcn(app, @PostTriggerTimeEditFieldValueChanged, true);
            app.PostTriggerTimeEditField.Tooltip = {'Post trigger time'};
            app.PostTriggerTimeEditField.Value = '20.0';
            app.TriggertimemsLabel = uilabel(app.ScopeAcquisitionPanel);
            app.TriggertimemsLabel.Text = 'Trigger time (ms)';
            app.PreTriggerTimeEditField = uieditfield(app.ScopeAcquisitionPanel, 'text');
            app.PreTriggerTimeEditField.ValueChangedFcn = createCallbackFcn(app, @PreTriggerTimeEditFieldValueChanged, true);
            app.PreTriggerTimeEditField.Tooltip = {'Pre Trigger time'};
            app.PreTriggerTimeEditField.Value = '0.0';
            app.VListBoxLabel = uilabel(app.ScopeAcquisitionPanel);
            app.VListBoxLabel.Text = 'V';
            app.VoltageRangeListBox = uilistbox(app.ScopeAcquisitionPanel);
            app.VoltageRangeListBox.Items = {'10 mV', '20 mV', '50 mV', '100 mV', '200 mV', '0.5 V', '1 V', '2 V', '5 V', '10 V', '20 V', '50 V', 'Max'};
            app.VoltageRangeListBox.Value = '20 mV';
            app.MotorScanTab = uitab(app.TabGroup);
            app.MotorScanTab.Title = 'Motor Scan';
            app.UIAxesPressure = uiaxes(app.MotorScanTab);
            xlabel(app.UIAxesPressure, 'Z (mm)')
            ylabel(app.UIAxesPressure, 'Pressure (Pa)')
            zlabel(app.UIAxesPressure, 'Z')
            app.LineDropDown_axis_selection = uidropdown(app.MotorScanTab);
            app.LineDropDown_axis_selection.Items = {'X', 'Y', 'Z', 'T'};
            app.LineDropDown_axis_selection.Tooltip = {'Axes to scan'};
            app.LineDropDown_axis_selection.Value = 'X';
            app.EditField_line_range = uieditfield(app.MotorScanTab, 'text');
            app.EditField_line_range.Tag = 'xrv';
            app.EditField_line_range.Tooltip = {'Linear scan plus minus range in mm.'};
            app.EditField_line_range.Value = '5.0';
            app.RotateCheckBox = uicheckbox(app.MotorScanTab);
            app.RotateCheckBox.Text = 'Rotate';
            app.EditField_rotation_range = uieditfield(app.MotorScanTab, 'text');
            app.EditField_rotation_range.Tag = 'xrv';
            app.EditField_rotation_range.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_rotation_range.Value = '90';
            app.EditField_line_step = uieditfield(app.MotorScanTab, 'text');
            app.EditField_line_step.Tag = 'xrv';
            app.EditField_line_step.Tooltip = {'Linear step in mm.'};
            app.EditField_line_step.Value = '1.0';
            app.EditField_rotation_step = uieditfield(app.MotorScanTab, 'text');
            app.EditField_rotation_step.Tag = 'xrv';
            app.EditField_rotation_step.Tooltip = {'Rotational step'};
            app.EditField_rotation_step.Value = '10';
            app.ScanMotorButton = uibutton(app.MotorScanTab, 'state');
            app.ScanMotorButton.ValueChangedFcn = createCallbackFcn(app, @ScanMotorButtonValueChanged, true);
            app.ScanMotorButton.Text = 'Scan';
            app.EditField_timegate_start = uieditfield(app.MotorScanTab, 'text');
            app.EditField_timegate_start.Tag = 'xrv';
            app.EditField_timegate_start.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_timegate_start.Value = '1';
            app.EditField_timegate_width = uieditfield(app.MotorScanTab, 'text');
            app.EditField_timegate_width.Tag = 'xrv';
            app.EditField_timegate_width.Tooltip = {'Rotational step'};
            app.EditField_timegate_width.Value = '2';
            app.StartusLabel = uilabel(app.MotorScanTab);
            app.StartusLabel.Text = 'Start (us)';
            app.widthLabel = uilabel(app.MotorScanTab);
            app.widthLabel.Text = 'width';
            app.EditField_ScanAverages = uieditfield(app.MotorScanTab, 'text');
            app.EditField_ScanAverages.Tag = 'xrv';
            app.EditField_ScanAverages.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_ScanAverages.Value = '8';
            app.AveragesLabel = uilabel(app.MotorScanTab);
            app.AveragesLabel.Text = 'Averages';
            app.FrequencyResponseTab = uitab(app.TabGroup);
            app.FrequencyResponseTab.Title = 'Frequency Response';
            app.UIAxesPressure_2 = uiaxes(app.FrequencyResponseTab);
            xlabel(app.UIAxesPressure_2, 'Frequency (MHz)')
            ylabel(app.UIAxesPressure_2, 'Pressure (Pa)')
            zlabel(app.UIAxesPressure_2, 'Z')
            app.EditField_freq_start = uieditfield(app.FrequencyResponseTab, 'text');
            app.EditField_freq_start.Tag = 'xrv';
            app.EditField_freq_start.Tooltip = {'Linear scan plus minus range in mm.'};
            app.EditField_freq_start.Value = '8.0';
            app.EditField_freq_step = uieditfield(app.FrequencyResponseTab, 'text');
            app.EditField_freq_step.Tag = 'xrv';
            app.EditField_freq_step.Tooltip = {'Linear step in mm.'};
            app.EditField_freq_step.Value = '1.0';
            app.EditField_freq_stop = uieditfield(app.FrequencyResponseTab, 'text');
            app.EditField_freq_stop.Tag = 'xrv';
            app.EditField_freq_stop.Tooltip = {'Rotational step'};
            app.EditField_freq_stop.Value = '12.0';
            app.ScanFrequencyButton = uibutton(app.FrequencyResponseTab, 'state');
            app.ScanFrequencyButton.ValueChangedFcn = createCallbackFcn(app, @ScanFrequencyButtonValueChanged, true);
            app.ScanFrequencyButton.Text = 'Scan';
            app.EditField_timegate_start_2 = uieditfield(app.FrequencyResponseTab, 'text');
            app.EditField_timegate_start_2.Tag = 'xrv';
            app.EditField_timegate_start_2.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_timegate_start_2.Value = '1';
            app.EditField_timegate_width_2 = uieditfield(app.FrequencyResponseTab, 'text');
            app.EditField_timegate_width_2.Tag = 'xrv';
            app.EditField_timegate_width_2.Tooltip = {'Rotational step'};
            app.EditField_timegate_width_2.Value = '2';
            app.StartusLabel_2 = uilabel(app.FrequencyResponseTab);
            app.StartusLabel_2.Text = 'Start (us)';
            app.WidthLabel_2 = uilabel(app.FrequencyResponseTab);
            app.WidthLabel_2.Text = 'Width';
            app.EditField_ScanAverages_2 = uieditfield(app.FrequencyResponseTab, 'text');
            app.EditField_ScanAverages_2.Tag = 'xrv';
            app.EditField_ScanAverages_2.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_ScanAverages_2.Value = '8';
            app.AveragesLabel_2 = uilabel(app.FrequencyResponseTab);
            app.AveragesLabel_2.Text = 'Averages';
            app.FrequencyMHzLabel = uilabel(app.FrequencyResponseTab);
            app.FrequencyMHzLabel.Text = 'Frequency (MHz)';
            app.Label = uilabel(app.FrequencyResponseTab);
            app.Label.Text = ':';
            app.stepLabel = uilabel(app.FrequencyResponseTab);
            app.stepLabel.Text = 'step';
            app.DataControlTab = uitab(app.TabGroup);
            app.DataControlTab.Title = 'Data Control';
            app.OutputFolderEditFieldLabel = uilabel(app.DataControlTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolder = uieditfield(app.DataControlTab, 'text');
            app.OutputFolder.Editable = 'off';
            app.IdentificationEditFieldLabel = uilabel(app.DataControlTab);
            app.IdentificationEditFieldLabel.Text = 'Identification';
            app.IdentificationEditField = uieditfield(app.DataControlTab, 'text');
            app.IdentificationEditField.Value = 'TEST';
            app.BrowseButton = uibutton(app.DataControlTab, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Text = 'Browse';
            app.SaveDataCheckBox = uicheckbox(app.DataControlTab);
            app.SaveDataCheckBox.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroScan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end